classdef dataProcess < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SIMITUIFigure      matlab.ui.Figure
        KEditFieldLabel_4  matlab.ui.control.Label
        KLabel_2           matlab.ui.control.Label
        KEditFieldLabel_3  matlab.ui.control.Label
        concatDataButton   matlab.ui.control.Button
        lowpassFilter      matlab.ui.control.Button
        rotateButton       matlab.ui.control.Button
        kCompOutButton     matlab.ui.control.Button
        kCompButton        matlab.ui.control.Button
        findAngle          matlab.ui.control.Button
        LoadDataButton     matlab.ui.control.Button
        kcompoutWindPos    matlab.ui.control.NumericEditField
        windowTitle        matlab.ui.control.Label
        kcompoutWindLen    matlab.ui.control.NumericEditField
        kcompWindLen       matlab.ui.control.NumericEditField
        SIMIT_Hyperlink    matlab.ui.control.Hyperlink
        lowpassFreq        matlab.ui.control.NumericEditField
        Label_9            matlab.ui.control.Label
        Z_upperband        matlab.ui.control.Spinner
        ZSpinner_2Label    matlab.ui.control.Label
        Y_upperband        matlab.ui.control.Spinner
        YSpinner_2Label    matlab.ui.control.Label
        X_upperband        matlab.ui.control.Spinner
        XSpinnerLabel_3    matlab.ui.control.Label
        Z_lowerband        matlab.ui.control.Spinner
        ZSpinnerLabel_2    matlab.ui.control.Label
        Y_lowerband        matlab.ui.control.Spinner
        YSpinnerLabel_2    matlab.ui.control.Label
        X_lowerband        matlab.ui.control.Spinner
        XSpinnerLabel_2    matlab.ui.control.Label
        labLogo            matlab.ui.control.Image
        angleInfo          matlab.ui.control.Label
        Zangle             matlab.ui.control.Spinner
        ZSpinnerLabel      matlab.ui.control.Label
        Yangle             matlab.ui.control.Spinner
        YSpinnerLabel      matlab.ui.control.Label
        Xangle             matlab.ui.control.Spinner
        XSpinnerLabel      matlab.ui.control.Label
        fileNameBar        matlab.ui.control.EditField
        Label_8            matlab.ui.control.Label
        backgroundImage    matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: findAngle
        function findAngleButtonPushed(app, event)
            % 数据读取部分
            try
                [data, ~, filePath] = readsquidfiles;
                prompt = {'请输入所选数据的采样率：'};
                inputTitle = '输入采样率';
                dims = [1,10];
                definput = {'2000'};
                parameters = inputdlg(prompt, inputTitle, dims, definput);
                inputLine1 = strsplit(parameters{1,1});
                Fs = str2double(cell2mat(inputLine1));
                try
                    app.fileNameBar.Value = char(append(filePath(1), filePath(2)));
                catch
                    app.fileNameBar.Value = '文件名为空';
                end
            catch
                errordlg('文件读取错误', '错误')
            end
            % 角度寻优部分
            x0z = 0;
            x0y = 0;
            x0x = 0;
            z_lb = app.Z_lowerband.Value;
            z_ub = app.Z_upperband.Value;
            y_lb = app.Y_lowerband.Value;
            y_ub = app.Y_upperband.Value;
            x_lb = app.X_lowerband.Value;
            x_ub = app.X_upperband.Value;

            % Create optimization variables
            Z_angle = optimvar("Z_angle","LowerBound",z_lb,"UpperBound",z_ub);
            Y_angle = optimvar("Y_angle","LowerBound",y_lb,"UpperBound",y_ub);
            X_angle = optimvar("X_angle","LowerBound",x_lb,"UpperBound",x_ub);

            % Set initial starting point for the solver
            initialPoint.Z_angle = x0z;
            initialPoint.Y_angle = x0y;
            initialPoint.X_angle = x0x;

            % Create problem
            problem = optimproblem;

            % Define problem objective
            problem.Objective = fcn2optimexpr(@findAngleObjectiveFcn,Z_angle,Y_angle,...
                X_angle,data);

            % 设置非默认求解器选项
            options = optimoptions("fmincon","Display","iter","PlotFcn",["optimplotx",...
                "optimplotfvalconstr","optimplotfval","optimplotstepsize"]);

            % Display problem information
            show(problem);

            % Solve problem
            [solution,~,~] = solve(problem,initialPoint, "Solver","fmincon","Options",options);
            
            answer = struct2array(solution);
            dataR = rotate_3D(data, answer(3), answer(2), answer(1));
            plotWithTime(dataR, Fs)
            corArray = calCorr(dataR);
            msgbox({'角度寻优计算已完成',append('X: ', num2str(answer(1))), append('Y: ', num2str(answer(2))), append('Z: ', num2str(answer(3))), ...
                '旋转后各通道间相关系数为:', strcat('X: ', num2str(corArray(1)), ' Y: ', num2str(corArray(2)), ' Z: ', num2str(corArray(3)))})

            % 清除变量
            clearvars Z_angle Y_angle X_angle initialPoint options reasonSolverStopped...
                objectiveValue

        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            try
                % 数据读取部分
                [dataMat, ~, filePath] = readsquidfiles;
                try
                    app.fileNameBar.Value = char(append(filePath(1), filePath(2)));
                catch
                    app.fileNameBar.Value = '文件名为空';
                end
                sz_raw = size(dataMat);
                for i = 1 : sz_raw(2)
                    figure(i)
                    plot(dataMat(:, i))
                    title( strcat('第', num2str(i), '通道时域信号'))
                end
                prompt = {'请输入所选的通道编号：', '请输入起始点与终止点坐标：', '请输入各通道灵敏度系数'};
                inputTitle = '选取数据与输入灵敏度';
                dims = [1,50];
                definput = {'1 2 3 4 5 6', append('1 ', num2str(sz_raw(1))), '35.2 35.2 35.2 35.2 35.2 35.2'};
                try
                    parameters = inputdlg(prompt, inputTitle, dims, definput);
                    inputLine1 = strsplit(parameters{1,1});
                    selectedChannel = zeros(1, size(inputLine1, 2));
                    for i = 1 : size(inputLine1, 2)
                        selectedChannel(i) = str2double(cell2mat(inputLine1(i)));
                    end
                    inputLine2 = strsplit(parameters{2,1});
                    startPoint = str2double(cell2mat(inputLine2(1)));
                    endPoint = str2double(cell2mat(inputLine2(2)));
                    inputLine3 = strsplit(parameters{3,1});
                    sens = zeros(1, size(inputLine3, 2));
                    for i = 1:size(inputLine3, 2)
                        sens(i) = str2double(cell2mat(inputLine3(i)));
                    end
                catch
                    errordlg("数据输入部分出错！", '错误')
                end
                % 数据处理部分
                selectData = chSwap(dataMat(startPoint:endPoint,:), selectedChannel);
%                 for i = 1:size(inputLine1, 2)
%                     selectData(:,i) = dataMat(startPoint:endPoint, selectedChannel(i));
%                 end
                sens = diag(sens);
                selectData = selectData * sens;
                % 将数据保存为mat文件
                try
                    figure
                    plot(selectData)
                    ylabel('B(nT)')
                    save selectData.mat selectData
                    msgbox({'文件保存成功', append('文件保存位置：', pwd, '\selectData.mat'), '文件名为selectData.mat'});
                catch
                    errordlg('文件保存失败', '错误')
                end
            catch
                if matDimDismatchFlag == 0
                    errordlg('文件读取失败', '错误')
                end
            end
        end

        % Button pushed function: rotateButton
        function rotateButtonPushed(app, event)
            % 读取数据部分
            try
                [data, ~, filePath] = readsquidfiles;
                prompt = {'请输入所选数据的采样率：'};
                inputTitle = '输入采样率';
                dims = [1,50];
                definput = {'2000'};
                parameters = inputdlg(prompt, inputTitle, dims, definput);
                inputLine1 = strsplit(parameters{1,1});
                Fs = str2double(cell2mat(inputLine1));
                try
                    app.fileNameBar.Value = char(append(filePath(1), filePath(2)));
                catch
                    app.fileNameBar.Value = '文件名为空';
                end
            catch
                errordlg('文件读取失败', '错误')
            end
            % 对mat文件进行旋转
            rotateData = rotate_3D(data, app.Zangle.Value, app.Yangle.Value, app.Xangle.Value);
            plotWithTime(data, Fs)
            hold on
            plotWithTime(rotateData, Fs)
            legend('原始信号', '旋转后信号')
            hold off
            try
                 save rotateData.mat rotateData
                 msgbox({'文件保存成功', append('文件保存位置：', pwd, '\rotateData.mat'), '文件名为rotateData.mat'});
            catch
                errordlg('文件保存失败', '错误')
            end
        end

        % Button pushed function: lowpassFilter
        function lowpassFilterButtonPushed(app, event)
            try
                [data, ~, filePath] = readsquidfiles;
                prompt = {'请输入所选数据的采样率：'};
                inputTitle = '输入采样率';
                dims = [1,50];
                definput = {'2000'};
                parameters = inputdlg(prompt, inputTitle, dims, definput);
                inputLine1 = strsplit(parameters{1,1});
                Fs = str2double(cell2mat(inputLine1));
                try
                    app.fileNameBar.Value = char(append(filePath(1), filePath(2)));
                catch
                    app.fileNameBar.Value = '文件名为空';
                end
            catch
                errordlg('文件读取失败', '错误')
            end
            % 低通滤波部分
            LPdata = LPDSTest(data, app.lowpassFreq.Value/2, Fs);
            plotWithTime(LPdata, app.lowpassFreq.Value)
            title('滤波后信号')
            % 将数据保存为mat文件
            try
                save lowPassData.mat LPdata
                msgbox({'文件保存成功', append('文件保存位置：', pwd, '\lowPassData.mat'), '文件名为lowPassData.mat'});
            catch
                errordlg('文件保存失败', '错误')
            end
        end

        % Button pushed function: kCompButton
        function kCompButtonPushed(app, event)
            % 数据读取部分
            try
                [data, ~, filePath] = readsquidfiles;
                prompt = {'请输入所选数据的采样率：'};
                inputTitle = '输入采样率';
                dims = [1,20];
                definput = {'2000'};
                parameters = inputdlg(prompt, inputTitle, dims, definput);
                inputLine1 = strsplit(parameters{1,1});
                Fs = str2double(cell2mat(inputLine1));
                try
                    app.fileNameBar.Value = char(append(filePath(1), filePath(2)));
                catch
                    app.fileNameBar.Value = '文件名为空';
                end
            catch
                errordlg('文件读取失败', '错误')
            end
            % 数据处理部分
            try
                [kcompData, k] = kcomp(data, Fs, app.kcompoutWindLen.Value);
            catch
                errordlg('K系数内补偿时出错', '错误')
            end
            plotKcoe(k);
            try
                save kcompData.mat kcompData
                msgbox({'文件保存成功', append('文件保存位置：', pwd, '\kcompData.mat'), '文件名为kcompData.mat'});
            catch
                errordlg('文件保存失败', '错误')
            end
        end

        % Button pushed function: kCompOutButton
        function kCompOutButtonPushed(app, event)
            % 数据读取部分
            try
                [data1, ~, ~] = readsquidfiles;
                [data2, ~, ~] = readsquidfiles;
                app.fileNameBar.Value = 'k系数外补偿';
            catch
                errordlg('文件读取失败', '错误')
            end
            % 数据处理部分
            try
                [kcompoutData, k] = kcompout(data1, data2, app.kcompoutWindLen1.Value, app.kcompoutWindPos1.Value);
            catch
                errordlg('K系数外补偿时出错', '错误')
            end
            plotKcoe(k);
            try
                save kcompoutData.mat kcompoutData
                msgbox({'文件保存成功', append('文件保存位置：', pwd, '\kcompoutData.mat'), '文件名为kcompoutData.mat'});
            catch
                errordlg('文件保存失败', '错误')
            end
        end

        % Image clicked function: labLogo
        function labLogoImageClicked(app, event)
            web('http://www.sim.cas.cn/kybm2016/cdzx/')
        end

        % Callback function: SIMIT_Hyperlink
        function SIMIT_HyperlinkClicked(app, event)
             web('http://www.sim.cas.cn')
        end

        % Button pushed function: concatDataButton
        function concatDataButtonPushed(app, event)
            try
                [sys1data, ~, ~] = readsquidfiles;
                [sys2data, ~, ~] = readsquidfiles;
                app.fileNameBar.Value = '数据拼接';
            catch
                errordlg('文件读取失败', '错误')
            end
            try
                concatData = [sys1data, sys2data];
                figure
                plot(concatData)
            catch 
                errordlg("矩阵维度不匹配！", '错误')
            end
            try
                save concatData.mat concatData
                msgbox({'文件保存成功', append('文件保存位置：', pwd, '\concatData.mat'), '文件名为concatData.mat'});
            catch         
                errordlg('文件保存失败', '错误')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SIMITUIFigure = uifigure('Visible', 'off');
            app.SIMITUIFigure.Name = 'SIMIT低频弱磁信号处理工具 1.0Ver.';
            app.SIMITUIFigure.Icon = fullfile(pathToMLAPP, 'simitLogo.png');
            app.backgroundImage = uiimage(app.SIMITUIFigure);
            app.backgroundImage.ImageSource = fullfile(pathToMLAPP, 'backGround.png');
            app.Label_8 = uilabel(app.SIMITUIFigure);
            app.Label_8.Text = '当前处理文件';
            app.fileNameBar = uieditfield(app.SIMITUIFigure, 'text');
            app.fileNameBar.Value = '数据应为六列的列向量';
            app.XSpinnerLabel = uilabel(app.SIMITUIFigure);
            app.XSpinnerLabel.Text = 'X方向欧拉角';
            app.Xangle = uispinner(app.SIMITUIFigure);
            app.Xangle.Limits = [-180 180];
            app.YSpinnerLabel = uilabel(app.SIMITUIFigure);
            app.YSpinnerLabel.Text = 'Y方向欧拉角';
            app.Yangle = uispinner(app.SIMITUIFigure);
            app.Yangle.Limits = [-180 180];
            app.ZSpinnerLabel = uilabel(app.SIMITUIFigure);
            app.ZSpinnerLabel.Text = 'Z方向欧拉角';
            app.Zangle = uispinner(app.SIMITUIFigure);
            app.Zangle.Limits = [-180 180];
            app.angleInfo = uilabel(app.SIMITUIFigure);
            app.angleInfo.Text = '角度寻优范围设置';
            app.labLogo = uiimage(app.SIMITUIFigure);
            app.labLogo.ImageClickedFcn = createCallbackFcn(app, @labLogoImageClicked, true);
            app.labLogo.ImageSource = fullfile(pathToMLAPP, 'labLogo.png');
            app.XSpinnerLabel_2 = uilabel(app.SIMITUIFigure);
            app.XSpinnerLabel_2.Text = 'X 下限';
            app.X_lowerband = uispinner(app.SIMITUIFigure);
            app.X_lowerband.Limits = [-180 180];
            app.X_lowerband.Value = -20;
            app.YSpinnerLabel_2 = uilabel(app.SIMITUIFigure);
            app.YSpinnerLabel_2.Text = 'Y 下限';
            app.Y_lowerband = uispinner(app.SIMITUIFigure);
            app.Y_lowerband.Limits = [-180 180];
            app.Y_lowerband.Value = -20;
            app.ZSpinnerLabel_2 = uilabel(app.SIMITUIFigure);
            app.ZSpinnerLabel_2.Text = 'Z 下限';
            app.Z_lowerband = uispinner(app.SIMITUIFigure);
            app.Z_lowerband.Limits = [-180 180];
            app.Z_lowerband.Value = -40;
            app.XSpinnerLabel_3 = uilabel(app.SIMITUIFigure);
            app.XSpinnerLabel_3.Text = 'X 上限';
            app.X_upperband = uispinner(app.SIMITUIFigure);
            app.X_upperband.Limits = [-180 180];
            app.X_upperband.Value = 20;
            app.YSpinner_2Label = uilabel(app.SIMITUIFigure);
            app.YSpinner_2Label.Text = 'Y 上限';
            app.Y_upperband = uispinner(app.SIMITUIFigure);
            app.Y_upperband.Limits = [-180 180];
            app.Y_upperband.Value = 20;
            app.ZSpinner_2Label = uilabel(app.SIMITUIFigure);
            app.ZSpinner_2Label.Text = 'Z 上限';
            app.Z_upperband = uispinner(app.SIMITUIFigure);
            app.Z_upperband.Limits = [-180 180];
            app.Z_upperband.Value = 40;
            app.Label_9 = uilabel(app.SIMITUIFigure);
            app.Label_9.Text = '低通滤波截止频率';
            app.lowpassFreq = uieditfield(app.SIMITUIFigure, 'numeric');
            app.lowpassFreq.Limits = [0 Inf];
            app.lowpassFreq.Value = 10;
            app.SIMIT_Hyperlink = uihyperlink(app.SIMITUIFigure);
            app.SIMIT_Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @SIMIT_HyperlinkClicked, true);
            app.SIMIT_Hyperlink.Text = 'SIMIT';
            app.kcompWindLen = uieditfield(app.SIMITUIFigure, 'numeric');
            app.kcompWindLen.Value = 1000;
            app.kcompoutWindLen = uieditfield(app.SIMITUIFigure, 'numeric');
            app.kcompoutWindLen.Value = 1000;
            app.windowTitle = uilabel(app.SIMITUIFigure);
            app.windowTitle.Text = '多通道低频磁信号处理软件';
            app.kcompoutWindPos = uieditfield(app.SIMITUIFigure, 'numeric');
            app.kcompoutWindPos.Value = 5;
            app.LoadDataButton = uibutton(app.SIMITUIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = '选择数据';
            app.findAngle = uibutton(app.SIMITUIFigure, 'push');
            app.findAngle.ButtonPushedFcn = createCallbackFcn(app, @findAngleButtonPushed, true);
            app.findAngle.Text = '自动角度寻优';
            app.kCompButton = uibutton(app.SIMITUIFigure, 'push');
            app.kCompButton.ButtonPushedFcn = createCallbackFcn(app, @kCompButtonPushed, true);
            app.kCompButton.Text = 'K系数内补偿';
            app.kCompOutButton = uibutton(app.SIMITUIFigure, 'push');
            app.kCompOutButton.ButtonPushedFcn = createCallbackFcn(app, @kCompOutButtonPushed, true);
            app.kCompOutButton.Text = 'K系数外补偿';
            app.rotateButton = uibutton(app.SIMITUIFigure, 'push');
            app.rotateButton.ButtonPushedFcn = createCallbackFcn(app, @rotateButtonPushed, true);
            app.rotateButton.Text = '角度转换';
            app.lowpassFilter = uibutton(app.SIMITUIFigure, 'push');
            app.lowpassFilter.ButtonPushedFcn = createCallbackFcn(app, @lowpassFilterButtonPushed, true);
            app.lowpassFilter.Text = '低通滤波';
            app.concatDataButton = uibutton(app.SIMITUIFigure, 'push');
            app.concatDataButton.ButtonPushedFcn = createCallbackFcn(app, @concatDataButtonPushed, true);
            app.concatDataButton.Text = '数据拼接';
            app.KEditFieldLabel_3 = uilabel(app.SIMITUIFigure);
            app.KEditFieldLabel_3.Text = 'K系数内补偿窗长';
            app.KLabel_2 = uilabel(app.SIMITUIFigure);
            app.KLabel_2.Text = 'K系数外补偿窗长';
            app.KEditFieldLabel_4 = uilabel(app.SIMITUIFigure);
            app.KEditFieldLabel_4.Text = 'K系数外补偿窗位置';
            % Show the figure after all components are created
            app.SIMITUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dataProcess

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SIMITUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SIMITUIFigure)
        end
    end
end